classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InterfazPrincipal               matlab.ui.container.TabGroup
        ConfigTab                       matlab.ui.container.Tab
        LucesvisionporcomputadorPanel   matlab.ui.container.Panel
        EditB                           matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        EditG                           matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        EditR                           matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        GreenKnob                       matlab.ui.control.Knob
        GreenKnobLabel                  matlab.ui.control.Label
        BlueKnob                        matlab.ui.control.Knob
        BlueKnobLabel                   matlab.ui.control.Label
        RedKnob                         matlab.ui.control.Knob
        RedKnobLabel                    matlab.ui.control.Label
        BLamp                           matlab.ui.control.Lamp
        BLampLabel                      matlab.ui.control.Label
        GLamp                           matlab.ui.control.Lamp
        GLampLabel                      matlab.ui.control.Label
        RLamp                           matlab.ui.control.Lamp
        RLampLabel                      matlab.ui.control.Label
        LabelBaudios                    matlab.ui.control.Label
        BAUDIOSLabel                    matlab.ui.control.Label
        LabelPuerto                     matlab.ui.control.Label
        PUERTOLabel                     matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        LampLabel                       matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        ConfigButton                    matlab.ui.control.Button
        ModomanualTab                   matlab.ui.container.Tab
        Tabsmodomanual                  matlab.ui.container.TabGroup
        Segmento0Tab                    matlab.ui.container.Tab
        sendV2                          matlab.ui.control.Button
        sendV3                          matlab.ui.control.Button
        sendV1                          matlab.ui.control.Button
        GaugeV3                         matlab.ui.control.Gauge
        V2Label                         matlab.ui.control.Label
        GaugeV2                         matlab.ui.control.Gauge
        V2Gauge_4Label                  matlab.ui.control.Label
        GaugeV1                         matlab.ui.control.Gauge
        V1Gauge_2Label                  matlab.ui.control.Label
        Segmento1Tab                    matlab.ui.container.Tab
        GaugeV6                         matlab.ui.control.Gauge
        V0Gauge_3Label_2                matlab.ui.control.Label
        GaugeV5                         matlab.ui.control.Gauge
        V5GaugeLabel                    matlab.ui.control.Label
        GaugeV4                         matlab.ui.control.Gauge
        V4Gauge_2Label                  matlab.ui.control.Label
        sendV5                          matlab.ui.control.Button
        sendV6                          matlab.ui.control.Button
        sendV4                          matlab.ui.control.Button
        Segmento2Tab                    matlab.ui.container.Tab
        GaugeV9                         matlab.ui.control.Gauge
        V0Gauge_3Label_4                matlab.ui.control.Label
        GaugeV8                         matlab.ui.control.Gauge
        V8GaugeLabel                    matlab.ui.control.Label
        GaugeV7                         matlab.ui.control.Gauge
        V0Gauge_3Label_3                matlab.ui.control.Label
        sendV8                          matlab.ui.control.Button
        sendV9                          matlab.ui.control.Button
        sendV7                          matlab.ui.control.Button
        PressuretosendSlider            matlab.ui.control.Slider
        PressuretosendSliderLabel       matlab.ui.control.Label
        VisionporcomputadorTab          matlab.ui.container.Tab
        Panel2                          matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        setButton                       matlab.ui.control.Button
        Z_offsetEditField               matlab.ui.control.NumericEditField
        Z_offsetEditFieldLabel          matlab.ui.control.Label
        zeroButton                      matlab.ui.control.Button
        PosicionText                    matlab.ui.control.TextArea
        PosicionTextAreaLabel           matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        UIAxes_yz                       matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        EulerEditField                  matlab.ui.control.EditField
        EulerEditFieldLabel             matlab.ui.control.Label
        UIAxes_xz                       matlab.ui.control.UIAxes
        connect                         matlab.ui.control.Button
        CapturarposicionButton          matlab.ui.control.Button
        Tab                             matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        AjustesTab                      matlab.ui.container.Tab
        CapturadedatosTab               matlab.ui.container.Tab
        RecogidadedatosTab              matlab.ui.container.Tab
        TempambienteEditField           matlab.ui.control.NumericEditField
        TempambienteEditFieldLabel      matlab.ui.control.Label
        CoordenadashomogeneasPanel      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        datosmth                        matlab.ui.control.UIAxes
        VisionporcomputadorPanel        matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        datosyz                         matlab.ui.control.UIAxes
        datosxz                         matlab.ui.control.UIAxes
        Lamp_2                          matlab.ui.control.Lamp
        Label_2                         matlab.ui.control.Label
        ManualPanel                     matlab.ui.container.Panel
        GuardarButton                   matlab.ui.control.Button
        DesinflarButton                 matlab.ui.control.Button
        TomarposicionButton             matlab.ui.control.Button
        EnviarButton                    matlab.ui.control.Button
        v9                              matlab.ui.control.NumericEditField
        v8                              matlab.ui.control.NumericEditField
        v7                              matlab.ui.control.NumericEditField
        v6                              matlab.ui.control.NumericEditField
        V1EditField_3Label_2            matlab.ui.control.Label
        V1EditField_6                   matlab.ui.control.NumericEditField
        v5                              matlab.ui.control.NumericEditField
        V1EditField_2Label_2            matlab.ui.control.Label
        v4                              matlab.ui.control.NumericEditField
        v3                              matlab.ui.control.NumericEditField
        V3EditFieldLabel                matlab.ui.control.Label
        v2                              matlab.ui.control.NumericEditField
        V2EditFieldLabel                matlab.ui.control.Label
        v1                              matlab.ui.control.NumericEditField
        V1EditFieldLabel                matlab.ui.control.Label
        NombrevariableEditField         matlab.ui.control.EditField
        NombrevariableEditFieldLabel    matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TextAreaLabel                   matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        PpresostatoMpaEditField         matlab.ui.control.NumericEditField
        PpresostatoMpaEditFieldLabel    matlab.ui.control.Label
        PresionminimaEditField          matlab.ui.control.NumericEditField
        PresionminimaEditFieldLabel     matlab.ui.control.Label
        PresionmaximaEditField          matlab.ui.control.NumericEditField
        PresionmaximaEditFieldLabel     matlab.ui.control.Label
        NumerodemuestrasEditField       matlab.ui.control.NumericEditField
        NumerodemuestrasEditFieldLabel  matlab.ui.control.Label
    end

    
    %Atributos publicos
    properties (Access = public) 
        mi_arduino; % El puerto serial para acceder desde más apps
        LosBaudios = 9600; %El dato de los Baudios que actualizaremos en otra app
        ElPuerto = "COM8"; %El dato del puerto del arduino que actualizaremos en otra app
        configset = false; 
        caso = '2';
        
        %Presion que se va a enviar
        millis2send = 0;

        % Objeto robot
        %miRobot  

        %yz -> logitech
        %xz -> la otra

        millisSendedToValves;

        gaugearray;

        img_yz;
        img_xz;
        cam_yz;
        cam_xz;
        cameraParams_yz;
        cameraParams_xz;

        rotyz;
        rotxz;
        transyz;
        transxz;
        matyz;
        matxz;
        p;
        o;
        r;
        g;
        b;
        

        rot_m;
        euler_m

        dy = 0;
        dx = 0;
        dz = 0;
        d = 245;
        z_offset = 0;

        % Datos recogidos
        pmin = 100;
        pmax = 300;
        n = 10;
        temp_amb = 20;
        p_presostato = 0.1;
        elapsed_time = 0;

        nom = 'dataset';
        mydata

        rd_c = 0;
        stopped = 0;
       

        % Vision
        radios = [68 68 100]';
        centros = [1 0 0; 0 1 0; 0 0 1];


    end   


    methods (Access = public)
    
        function updateplot(app, baudio, com) %funcion para actualizar valores desde otra app
            
            app.LosBaudios = baudio;   %almacenamos las variables como propiedades            
            app.ElPuerto = com;
            app.configset = true;
            
            
            app.ConfigButton.Enable = true;     %desbloqueamos este boton al cerrar la otra app
            app.ConnectButton.Enable = true;
        end
        
    end


    methods (Access = private)
        
        % Actualiza los visores de presiones en el modo manual
        function uptdateGauges(app)
            
            aux = app.millisSendedToValves;
            
            app.GaugeV1.Value = aux(1);
            app.GaugeV2.Value = aux(2);
            app.GaugeV3.Value = aux(3);

            app.GaugeV4.Value = aux(4);
            app.GaugeV5.Value = aux(5);
            app.GaugeV6.Value = aux(6);

            app.GaugeV7.Value = aux(7);
            app.GaugeV8.Value = aux(8);
            app.GaugeV9.Value = aux(9);

             
        end
        
        function str = point2str(~, point)
            str = "(" + point(1) + ", " + point(2) + ", " + point(3) + ")";
            
        end
        
        function results = sendline(app, string2seng)
            writeline
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.millisSendedToValves = [0 0 0 0 0 0 0 0 0];
            app.StartButton.Enable = "off";
            app.DesinflarButton.Enable = "off";
            app.EnviarButton.Enable = "off";
            app.TomarposicionButton.Enable = "off";
            app.GuardarButton.Enable = "off";	
            
            % Toma de datos
            app.NumerodemuestrasEditField.Value = app.n;
            app.PresionmaximaEditField.Value = app.pmax;
            app.PresionminimaEditField.Value = app.pmin;
            app.PpresostatoMpaEditField.Value = app.p_presostato;
            app.TempambienteEditField.Value = app.temp_amb;
            app.NombrevariableEditField.Value = app.nom;
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                
                %app.mi_arduino=serialport(app.ElPuerto,app.LosBaudios);
                app.mi_arduino = serialport('COM22', 9600);
                %configureTerminator(app.mi_arduino,"CR/LF", "LF");
                %configureTerminator(app.mi_arduino,"CR/LF");
                %flush(app.mi_arduino);                
               
                %app.ConfigButton.Enable = false;
                %app.ConectarBoton.Enable = false;
                %pause(2);
                app.Lamp.Color = 'green';
                app.EnviarButton.Enable = "on";
                configureTerminator(obj,"CR/LF", "LF");
                
                
            catch
                VentanaAdvertir;
            end
        end

        % Button pushed function: ConfigButton
        function ConfigButtonPushed(app, event)
            
            app.ConnectButton.Enable = false;
            VentanaArduino(app);
        

        end

        % Value changed function: PressuretosendSlider
        function PressuretosendSliderValueChanged(app, event)

            app.millis2send = app.PressuretosendSlider.Value;
        end

        % Button pushed function: sendV1
        function sendV1ButtonPushed(app, event)

            writeOneValveMillis(app.mi_arduino , 0, app.millis2send);
            app.millisSendedToValves(1) = app.millis2send + app.millisSendedToValves(1);
            app.uptdateGauges();
        end

        % Button pushed function: sendV2
        function sendV2ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 1, app.millis2send);
            app.millisSendedToValves(2) = app.millis2send + app.millisSendedToValves(2);
            app.uptdateGauges();
        end

        % Button pushed function: sendV3
        function sendV3ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 2, app.millis2send);
            app.millisSendedToValves(3) = app.millis2send + app.millisSendedToValves(3);
            app.uptdateGauges();
        end

        % Button pushed function: sendV4
        function sendV4ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 3, app.millis2send);
            app.millisSendedToValves(4) = app.millis2send + app.millisSendedToValves(4);
            app.uptdateGauges();
        end

        % Button pushed function: sendV5
        function sendV5ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 4, app.millis2send);
            app.millisSendedToValves(5) = app.millis2send + app.millisSendedToValves(5);
            app.uptdateGauges();
        end

        % Button pushed function: sendV6
        function sendV6ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 5, app.millis2send);
            app.millisSendedToValves(6) = app.millis2send + app.millisSendedToValves(6);
            app.uptdateGauges();
        end

        % Button pushed function: sendV7
        function sendV7ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 6, app.millis2send);
            app.millisSendedToValves(7) = app.millis2send + app.millisSendedToValves(7);
            app.uptdateGauges();
        end

        % Button pushed function: sendV8
        function sendV8ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 7, app.millis2send);
            app.millisSendedToValves(8) = app.millis2send + app.millisSendedToValves(8);
            app.uptdateGauges();
        end

        % Button pushed function: sendV9
        function sendV9ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 8, app.millis2send);
            app.millisSendedToValves(9) = app.millis2send + app.millisSendedToValves(9);
            app.uptdateGauges();
        end

        % Button pushed function: CapturarposicionButton
        function CapturarposicionButtonPushed(app, event)
            
            % Tomamos imagen con la camara 1 y 2S
            app.img_yz = snapshot(app.cam_yz);
            app.img_xz = snapshot(app.cam_xz);

            imshow(app.img_yz,'Parent',app.UIAxes_yz);
            imshow(app.img_xz, 'Parent', app.UIAxes_xz);
            
            %para el verde, rojo y azul, capturamos la posicion del punto
            % Buscamos los puntos rojo, verde y azul en las dos imagenes.
            yz_r = findPoint(app.img_yz, 'o',"yz");
            yz_g = findPoint(app.img_yz, 'g',"yz");
            yz_b = findPoint(app.img_yz, 'b',"yz");
            xz_r = findPoint(app.img_xz, 'o',"xz");
            xz_g = findPoint(app.img_xz, 'g',"xz");
            xz_b = findPoint(app.img_xz, 'b',"xz");


            % Ploteamos las cosas en los ejes
            hold(app.UIAxes_yz, 'on')
            hold(app.UIAxes_xz, 'on')
            plot(yz_g(1) ,yz_g(2), 'o', 'Parent',app.UIAxes_yz, 'Color','green');
            plot(xz_g(1), xz_g(2), 'o', 'Parent',app.UIAxes_xz, 'Color','green')
            plot(yz_b(1), yz_b(2), 'o', 'Parent',app.UIAxes_yz, 'Color','red');
            plot(xz_b(1), xz_b(2), 'o', 'Parent',app.UIAxes_xz, 'Color','red')
            plot(yz_r(1), yz_r(2), 'o', 'Parent',app.UIAxes_yz, 'Color','red');
            plot(xz_r(1), xz_r(2), 'o', 'Parent',app.UIAxes_xz, 'Color','red')
            plot([yz_b(1) yz_g(1)], [yz_b(2) yz_g(2)], 'g', 'Parent',app.UIAxes_yz);
            plot([yz_b(1) yz_r(1)], [yz_b(2) yz_r(2)], 'g', 'Parent',app.UIAxes_yz);
            plot([yz_g(1) yz_r(1)], [yz_g(2) yz_r(2)], 'g', 'Parent',app.UIAxes_yz);
            plot([xz_b(1) xz_g(1)], [xz_b(2) xz_g(2)], 'g', 'Parent',app.UIAxes_xz);
            plot([xz_b(1) xz_r(1)], [xz_b(2) xz_r(2)], 'g', 'Parent',app.UIAxes_xz);
            plot([xz_g(1) xz_r(1)], [xz_g(2) xz_r(2)], 'g', 'Parent',app.UIAxes_xz);
            
            
            app.r = getCoordinates(yz_r,xz_r,app.matyz, app.matxz, app.dx, app.dy, app.dz, app.z_offset);
            app.g = getCoordinates(yz_g,xz_g,app.matyz, app.matxz, app.dx, app.dy, app.dz, app.z_offset);
            app.b = getCoordinates(yz_b,xz_b,app.matyz, app.matxz, app.dx, app.dy, app.dz, app.z_offset);
            
           
            hold on

            
            [app.rot_m, app.euler_m] = getRotations(app.r, app.g, app.b);
            
            % Pasamos matriz de rotacion a cuaternios, 
            quaternion = rotm2quat(app.rot_m);
            disp(quaternion);
            hold(app.UIAxes, 'on');
                
            % Pasamos matriz de rotacion a angulos de euler
            euler = rotm2eul(app.rot_m);

            hold(app.UIAxes, "on");
            
            rv = [app.r(1) - app.g(1), app.r(2) - app.g(2), app.r(3) - app.g(3)];
            bv = [app.b(1) - app.g(1), app.b(2) - app.g(2), app.b(3) - app.g(3)];
            zv = cross(rv, bv);
            rv = rv / norm(rv);
            bv = bv / norm(bv);

            zv = zv / norm(zv);


            quiver3(app.g(1), app.g(2), app.g(3), rv(1), rv(2), rv(3), 'Parent', app.UIAxes, 'Color', 'red');
            quiver3(app.g(1), app.g(2), app.g(3), bv(1) , bv(2), bv(3), 'Parent', app.UIAxes, 'Color', 'blue');
            quiver3(app.g(1), app.g(2), app.g(3), zv(1) , zv(2), zv(3), 'Parent', app.UIAxes, 'Color', 'green');


            %plot(app.p(2),app.p(3), 'o', 'Parent',app.UIAxes_yz);

            %plot(app.p(1),app.p(2), 'o', 'Parent',app.UIAxes_xz);
            valuestr = "Green: " + num2str(app.g(1)) + ", " + num2str(app.g(2)) + ", " + num2str(app.g(3))+ "" + ...
                + newline + "red: " + num2str(app.r(1)) + ", " + num2str(app.r(2)) + ", " + num2str(app.r(3))+ "" + ...
                + newline + "blue: " + num2str(app.b(1)) + ", " + num2str(app.b(2)) + ", " + num2str(app.b(3))+ "" + ...
                + newline + "euler: " + num2str(rad2deg(euler(1))) + ", " + num2str(rad2deg(euler(2))) + ", " + num2str(rad2deg(euler(3)))+ "" + ...
            " dy = " + num2str(app.dy) + "" + ...
                " dx = " + num2str(app.dx);
            app.PosicionText.Value = valuestr;

            eulerstr = "euler: " + num2str(rad2deg(euler(1))) + ", " + num2str(rad2deg(euler(2))) + ", " + num2str(rad2deg(euler(3)));
            app.EulerEditField.Value = eulerstr;
            

        end

        % Button pushed function: connect
        function connectButtonPushed(app, event)
            app.cam_xz = webcam("USB Camera");
            app.cam_yz = webcam("Logitech HD Webcam C270");
            tempX = load('C:\TFG_software\Code_matlab\par.mat','cameraParams');
            app.cameraParams_yz = tempX.cameraParams;
            tempX = load('C:\TFG_software\Code_matlab\par.mat','cameraParams_xz');
            app.cameraParams_xz = tempX.cameraParams_xz;

            
            [app.rotyz, app.transyz] = findCameraPose(app.cam_yz, app.cameraParams_yz);
            [app.rotxz, app.transxz] = findCameraPose(app.cam_xz, app.cameraParams_xz);
            
            
            figure;
            
            app.matyz = cameraMatrix(app.cameraParams_yz, app.rotyz, app.transyz);
            app.matxz = cameraMatrix(app.cameraParams_xz, app.rotxz, app.transxz);            
            

            app.BLamp.Color = "Green";

            hold off
        end

        % Button pushed function: zeroButton
        function zeroButtonPushed(app, event)
        %Objetivo es transformar las matrices de rotacion de las dos
        %camaras para hacer home del origen

            app.dx = 0;
            app.dy = 0;
            app.dz = 0;
            % Tomamos imagenes con las dos camaras
            app.img_yz = snapshot(app.cam_yz);
            app.img_xz = snapshot(app.cam_xz);
            imshow(app.img_yz,'Parent',app.UIAxes_yz);
            imshow(app.img_xz, 'Parent', app.UIAxes_xz);
            
            % Buscamos los puntos rojo, verde y azul en las dos imagenes.
            yz_r = findPoint(app.img_yz, 'o',"yz");
            yz_g = findPoint(app.img_yz, 'g',"yz");
            yz_b = findPoint(app.img_yz, 'b',"yz");
            xz_r = findPoint(app.img_xz, 'o',"xz");
            xz_g = findPoint(app.img_xz, 'g',"xz");
            xz_b = findPoint(app.img_xz, 'b',"xz");

            % Encontramos las coordenadas de dichos puntos respecto al
            % sistema fijo
            app.r = getCoordinates(yz_r,xz_r,app.matyz, app.matxz, app.dx, app.dy, app.dz, app.z_offset);
            app.g = getCoordinates(yz_g,xz_g,app.matyz, app.matxz, app.dx, app.dy, app.dz, app.z_offset);
            app.b = getCoordinates(yz_b,xz_b,app.matyz, app.matxz, app.dx, app.dy, app.dz, app.z_offset);

            % Los dibujamos en los ejes
            hold(app.UIAxes_yz, 'on')
            hold(app.UIAxes_xz, 'on')
            plot(yz_g(1) ,yz_g(2), 'o', 'Parent',app.UIAxes_yz, 'Color','green');
            plot(xz_g(1), xz_g(2), 'o', 'Parent',app.UIAxes_xz, 'Color','green')
            plot(yz_b(1), yz_b(2), 'o', 'Parent',app.UIAxes_yz, 'Color','red');
            plot(xz_b(1), xz_b(2), 'o', 'Parent',app.UIAxes_xz, 'Color','red')
            plot(yz_r(1), yz_r(2), 'o', 'Parent',app.UIAxes_yz, 'Color','red');
            plot(xz_r(1), xz_r(2), 'o', 'Parent',app.UIAxes_xz, 'Color','red')
            plot([yz_b(1) yz_g(1)], [yz_b(2) yz_g(2)], 'g', 'Parent',app.UIAxes_yz);
            plot([yz_b(1) yz_r(1)], [yz_b(2) yz_r(2)], 'g', 'Parent',app.UIAxes_yz);
            plot([yz_g(1) yz_r(1)], [yz_g(2) yz_r(2)], 'g', 'Parent',app.UIAxes_yz);
            plot([xz_b(1) xz_g(1)], [xz_b(2) xz_g(2)], 'g', 'Parent',app.UIAxes_xz);
            plot([xz_b(1) xz_r(1)], [xz_b(2) xz_r(2)], 'g', 'Parent',app.UIAxes_xz);
            plot([xz_g(1) xz_r(1)], [xz_g(2) xz_r(2)], 'g', 'Parent',app.UIAxes_xz);
            
            app.dx = app.g(1);
            app.dy = app.g(2);
            app.dz = app.g(3);


        end

        % Button pushed function: setButton
        function setButtonPushed(app, event)
            app.z_offset = app.Z_offsetEditField.Value;
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.pmax = app.PresionmaximaEditField.Value;
            app.pmin = app.PresionminimaEditField.Value;
            app.n = app.NumerodemuestrasEditField.Value;
            app.nom = app.NombrevariableEditField.Value;
            app.p_presostato = app.PpresostatoMpaEditField.Value;
            app.temp_amb = app.TempambienteEditField.Value;
         

            app.TextArea.Value = "Numero de muestras = " + num2str(app.n) + newline +  "Presion maxima = " + num2str(app.pmax) + " ms" + newline + "Presion minima = " + num2str(app.pmin)  + " ms" + newline + "Nombre: " + "dataset_" + app.nom + ".mat"+ newline + "P. presostato = " + num2str(app.p_presostato) + " Mpa" + newline + "T. Amb = " + num2str(app.temp_amb) + "ºC" + newline + newline + newline + "La presion se expresa en los milisegundos que permanece abierta la valvula. Esta medida esta directamente relacionada con la presion que se provoca en la vejiga correspondiente";
            app.StartButton.Enable = "on";

            app.rd_c = 1;
        end

        % Size changed function: ConfigTab
        function ConfigTabSizeChanged(app, event)
            
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.rd_c == 0
                app.TextArea.Value = "ERROR: no se han configurado los parametros";
            else
                % variable que contiene la cuenta del numero de muestra
                i = 1;
                
                % str que se envia para desinflar completamente el robot
                millisdesinflar = "w,1,-2000,-2000,-2000,-2000,-2000,-2000,-2000,-2000,-2000";
                % Desinflamos

                writeline(app.mi_arduino,millisdesinflar);
                pause(2);
                
                %almacenamos datos
                data.pmax = app.pmax;
                data.pmin = app.pmin;
                data.nummuestras = app.n;
                data.temp_amb = app.temp_amb;
                data.presion_presostato = app.p_presostato;

                % Inicializamos cronometro
                tic;
                
                % Para casa muestra tomada
                for i = 1:app.n
                    
                    string2send = "w,1";
                    
                    % Se determina que vejiga se queda sin hinchar de cada segmento
                    sin_hinchar = round(2* rand(1, 9) + 1); 
                    millis2send_ = ones(3);

                    % Generamos la combinacion de presiones que se enviara
                    for j = 1:9
                        for k = 1:3
                            if sin_hinchar(j) == k
                                millis2send_(j,k) = 0;
                            else    
                                millis2send_(j,k) = round((app.pmax - app.pmin) * rand(1:1) + app.pmin);
                            end
                        
                        end
                    end

                    millis2send__ = diag(ones(3));
                    millis2send__(1:3) = millis2send_(1,:);
                    millis2send__(4:6) = millis2send_(2,:);
                    millis2send__(7:9) = millis2send_(3,:); 

                    % Almacenamos presiones enviadas
                    data.inputs(i,1:9) = millis2send__(1:9);
    
                    pause(0.1);
                    for j = 1:9
                        string2send = string2send + "," +  num2str( millis2send__(j));
                    end

                    disp(string2send);
                    %Movemos a posicion indicada
                    writeToarduino(app.mi_arduino,string2send);

                    pause(max(millis2send__)/1000);

                    % Tomamos las imagenes
                    app.img_yz = snapshot(app.cam_yz);
                    img_name_yz = "cam_yz" + num2str(i) + ".jpeg";
                    imwrite(app.img_yz, img_name_yz, 'jpeg');
                    app.img_xz = snapshot(app.cam_xz);
                    img_name_xz = "cam_xz" + num2str(i) + ".jpeg";
                    imwrite(app.img_xz, img_name_xz, 'jpeg');

                    imshow(app.img_yz,'Parent',app.datosyz);
                    imshow(app.img_xz, 'Parent', app.datosxz);

                    hold(app.datosyz, 'on')
                    hold(app.datosxz, 'on')


                    %buscamos las marcas en la imagen yz
                    blue_yz = findPoint(app.img_yz, 'b', "yz"); 
                    data.img_points.yz.blue(i,1:2) = blue_yz;

                    green_yz = findPoint(app.img_yz, 'g',"yz"); 
                    data.img_points.yz.green(i,1:2) = green_yz;

                    red_yz = findPoint(app.img_yz, 'o', "yz"); 
                    data.img_points.yz.red(i,1:2) = red_yz;

                    %buscamos las marcas en la imagen xz
                    blue_xz = findPoint(app.img_xz, 'b', "xz"); 
                    data.img_points.xz.blue(i,1:2) = blue_xz;

                    green_xz = findPoint(app.img_xz, 'g', "yz"); 
                    data.img_points.xz.green(i,1:2) = green_xz;

                    red_xz = findPoint(app.img_xz, 'o', "yz"); 
                    data.img_points.xz.red(i,1:2) = red_xz;


                    plot(green_yz(1) ,green_yz(2), 'o', 'Parent', app.datosyz , 'Color','green');
                    plot(green_xz(1), green_xz(2), 'o', 'Parent',app.datosxz, 'Color','green')
                    plot(blue_yz(1), blue_yz(2), 'o', 'Parent',app.datosyz, 'Color','red');
                    plot(blue_xz(1), blue_xz(2), 'o', 'Parent',app.datosxz, 'Color','red')
                    plot(red_yz(1), red_yz(2), 'o', 'Parent',app.datosyz, 'Color','red');
                    plot(red_xz(1), red_xz(2), 'o', 'Parent',app.datosxz, 'Color','red')
                    plot([blue_yz(1) green_yz(1)], [blue_yz(2) green_yz(2)], 'g', 'Parent',app.datosyz);
                    plot([blue_yz(1) red_yz(1)], [blue_yz(2) red_yz(2)], 'g', 'Parent',app.datosyz);
                    plot([green_yz(1) red_yz(1)], [green_yz(2) red_yz(2)], 'g', 'Parent',app.datosyz);
                    plot([blue_xz(1) green_xz(1)], [blue_xz(2) green_xz(2)], 'g', 'Parent',app.datosxz);
                    plot([blue_xz(1) red_xz(1)], [blue_xz(2) red_xz(2)], 'g', 'Parent',app.datosxz);
                    plot([green_xz(1) red_xz(1)], [green_xz(2) red_xz(2)], 'g', 'Parent',app.datosxz);

                    
                    % Posicionamos las marcas en el sistema del mundo real
                    red = getCoordinates(red_yz, red_xz, app.matyz, app.matxz, app.dx,app.dy,app.dz, app.z_offset);
                    blue = getCoordinates(blue_yz, blue_xz, app.matyz, app.matxz, app.dx,app.dy,app.dz, app.z_offset);
                    green = getCoordinates(green_yz, green_xz, app.matyz, app.matxz, app.dx,app.dy,app.dz, app.z_offset);
                    data.points.red(i,1:3) = red;
                    data.points.blue(i,1:3) = blue;
                    data.points.green(i,1:3) = green;

                    % Obtenemos las rotaciones
                    [app.rot_m, app.euler_m] = getRotations(red, green, blue);    
                    data.outputs(i,1:3) = green(1:3);
                    data.outputs(i,4:6) = app.euler_m(1:3);


                    str2display = "Numero de muestra: " + i + newline + "Coordenadas azul: " +  app.point2str(blue) + newline + "Coordenadas verde: " + app.point2str(green) + newline + "Coordenadas azul: " + app.point2str(blue) + newline                         + "Presiones enviadas: " + string2send + newline  + "Angulos euler: " + num2str(rad2deg(app.euler_m(1))) + ", "  + num2str(rad2deg(app.euler_m(2))) + ", "  + num2str(rad2deg(app.euler_m(3)));

                    app.TextArea.Value = str2display;

                    % Se aumenta el contador de muestra
                    

                    % Desinflamos
                    writeline(app.mi_arduino,millisdesinflar);
                    pause(2 *2);


            rv = [red(1) - green(1), red(2) - green(2), red(3) - green(3)];
            
            bv = [blue(1) - green(1), blue(2) - green(2), blue(3) - green(3)];
            zv = cross(rv, bv);
            rv = rv / norm(rv);
            bv = bv / norm(bv);

            zv = zv / norm(zv);


            quiver3(green(1), green(2), green(3), rv(1), rv(2), rv(3), 'Parent', app.datosmth, 'Color', 'red');
            hold(app.datosmth, "on");
            quiver3(green(1), green(2), green(3), bv(1) , bv(2), bv(3), 'Parent', app.datosmth, 'Color', 'blue');
            quiver3(green(1), green(2), green(3), zv(1) , zv(2), zv(3), 'Parent', app.datosmth, 'Color', 'green');
            hold(app.datosmth, "off");


                end
                
                app.mydata = data;
                name = "dataset_" + app.nom + ".mat";
                app.elapsed_time = toc;
                data.elapsed_time = app.elapsed_time;
                save(name, "data");

                app.TextArea.Value = "== Toma de datos finalizada! ==" + newline + newline + "Numero de muestras = " + num2str(app.n) + newline +  "Presion maxima = " + num2str(app.pmax) + " ms" + newline + "Presion minima = " + num2str(app.pmin)  + " ms" + newline + "Nombre: " + "dataset_" + app.nom + ".mat"+ newline + "P. presostato = " + num2str(app.p_presostato) + " Mpa" + newline + "T. Amb = " + num2str(app.temp_amb) + "ºC" + newline + newline + newline + "Tiempo transcurrido: " + num2str(app.elapsed_time) + "s";
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopped = 1;
            app.StartButton.BackgroundColor = 'gray';
            app.TextArea.Value = "STOPPED";
        end

        % Value changed function: RedKnob
        function RedKnobValueChanged(app, event)
            value = app.RedKnob.Value;
            app.EditR.Value = value;
            writeline(app.mi_arduino, "v,1," + num2str(value));
        end

        % Value changed function: GreenKnob
        function GreenKnobValueChanged(app, event)
            value = app.GreenKnob.Value;
            app.EditG.Value = value;
            writeline(app.mi_arduino, "v,2," + num2str(value));
        end

        % Value changed function: BlueKnob
        function BlueKnobValueChanged(app, event)
            value = app.BlueKnob.Value;
            app.EditB.Value = value;
            writeline(app.mi_arduino, "v,3," + num2str(value));
        end

        % Value changed function: EditR
        function EditRValueChanged(app, event)
            value = app.EditR.Value;
            app.RedKnob.Value = value;
            writeline(app.mi_arduino, "v,1," + num2str(value));
        end

        % Value changed function: EditG
        function EditGValueChanged(app, event)
            value = app.EditG.Value;
            app.GreenKnob.Value = value;
            writeline(app.mi_arduino, "v,2," + num2str(value));
        end

        % Value changed function: EditB
        function EditBValueChanged(app, event)
            value = app.EditB.Value;
            app.BlueKnob.Value = value;
            writeline(app.mi_arduino, "v,3," + num2str(value));
        end

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InterfazPrincipal = uitabgroup(app.UIFigure);
            app.ConfigTab = uitab(app.InterfazPrincipal);
            app.ConfigTab.SizeChangedFcn = createCallbackFcn(app, @ConfigTabSizeChanged, true);
            app.ConfigTab.Title = 'Config';
            app.ConfigButton = uibutton(app.ConfigTab, 'push');
            app.ConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigButtonPushed, true);
            app.ConfigButton.Text = 'Config';
            app.ConnectButton = uibutton(app.ConfigTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.LampLabel = uilabel(app.ConfigTab);
            app.LampLabel.Text = {''; ''};
            app.Lamp = uilamp(app.ConfigTab);
            app.PUERTOLabel = uilabel(app.ConfigTab);
            app.PUERTOLabel.Text = 'PUERTO: ';
            app.LabelPuerto = uilabel(app.ConfigTab);
            app.BAUDIOSLabel = uilabel(app.ConfigTab);
            app.BAUDIOSLabel.Text = 'BAUDIOS';
            app.LabelBaudios = uilabel(app.ConfigTab);
            app.LabelBaudios.Text = '';
            app.LucesvisionporcomputadorPanel = uipanel(app.ConfigTab);
            app.LucesvisionporcomputadorPanel.Title = 'Luces vision por computador';
            app.RLampLabel = uilabel(app.LucesvisionporcomputadorPanel);
            app.RLampLabel.Text = 'R';
            app.RLamp = uilamp(app.LucesvisionporcomputadorPanel);
            app.GLampLabel = uilabel(app.LucesvisionporcomputadorPanel);
            app.GLampLabel.Text = 'G';
            app.GLamp = uilamp(app.LucesvisionporcomputadorPanel);
            app.BLampLabel = uilabel(app.LucesvisionporcomputadorPanel);
            app.BLampLabel.Text = 'B';
            app.BLamp = uilamp(app.LucesvisionporcomputadorPanel);
            app.RedKnobLabel = uilabel(app.LucesvisionporcomputadorPanel);
            app.RedKnobLabel.Text = 'Red';
            app.RedKnob = uiknob(app.LucesvisionporcomputadorPanel, 'continuous');
            app.RedKnob.Limits = [-1 255];
            app.RedKnob.ValueChangedFcn = createCallbackFcn(app, @RedKnobValueChanged, true);
            app.BlueKnobLabel = uilabel(app.LucesvisionporcomputadorPanel);
            app.BlueKnobLabel.Text = 'Blue';
            app.BlueKnob = uiknob(app.LucesvisionporcomputadorPanel, 'continuous');
            app.BlueKnob.Limits = [-1 255];
            app.BlueKnob.ValueChangedFcn = createCallbackFcn(app, @BlueKnobValueChanged, true);
            app.GreenKnobLabel = uilabel(app.LucesvisionporcomputadorPanel);
            app.GreenKnobLabel.Text = 'Green';
            app.GreenKnob = uiknob(app.LucesvisionporcomputadorPanel, 'continuous');
            app.GreenKnob.Limits = [-1 255];
            app.GreenKnob.ValueChangedFcn = createCallbackFcn(app, @GreenKnobValueChanged, true);
            app.Label = uilabel(app.LucesvisionporcomputadorPanel);
            app.Label.Text = '';
            app.EditR = uieditfield(app.LucesvisionporcomputadorPanel, 'numeric');
            app.EditR.ValueChangedFcn = createCallbackFcn(app, @EditRValueChanged, true);
            app.EditField_2Label = uilabel(app.LucesvisionporcomputadorPanel);
            app.EditField_2Label.Text = '';
            app.EditG = uieditfield(app.LucesvisionporcomputadorPanel, 'numeric');
            app.EditG.ValueChangedFcn = createCallbackFcn(app, @EditGValueChanged, true);
            app.EditField_3Label = uilabel(app.LucesvisionporcomputadorPanel);
            app.EditField_3Label.Text = '';
            app.EditB = uieditfield(app.LucesvisionporcomputadorPanel, 'numeric');
            app.EditB.ValueChangedFcn = createCallbackFcn(app, @EditBValueChanged, true);
            app.ModomanualTab = uitab(app.InterfazPrincipal);
            app.ModomanualTab.Title = 'Modo manual';
            app.PressuretosendSliderLabel = uilabel(app.ModomanualTab);
            app.PressuretosendSliderLabel.Text = 'Pressure to send';
            app.PressuretosendSlider = uislider(app.ModomanualTab);
            app.PressuretosendSlider.Limits = [-1000 1000];
            app.PressuretosendSlider.ValueChangedFcn = createCallbackFcn(app, @PressuretosendSliderValueChanged, true);
            app.Tabsmodomanual = uitabgroup(app.ModomanualTab);
            app.Segmento0Tab = uitab(app.Tabsmodomanual);
            app.Segmento0Tab.Title = 'Segmento 0';
            app.V1Gauge_2Label = uilabel(app.Segmento0Tab);
            app.V1Gauge_2Label.Text = 'V1';
            app.GaugeV1 = uigauge(app.Segmento0Tab, 'circular');
            app.GaugeV1.Limits = [-5000 5000];
            app.V2Gauge_4Label = uilabel(app.Segmento0Tab);
            app.V2Gauge_4Label.Text = 'V2';
            app.GaugeV2 = uigauge(app.Segmento0Tab, 'circular');
            app.GaugeV2.Limits = [-5000 5000];
            app.V2Label = uilabel(app.Segmento0Tab);
            app.V2Label.Text = 'V3';
            app.GaugeV3 = uigauge(app.Segmento0Tab, 'circular');
            app.GaugeV3.Limits = [-5000 5000];
            app.sendV1 = uibutton(app.Segmento0Tab, 'push');
            app.sendV1.ButtonPushedFcn = createCallbackFcn(app, @sendV1ButtonPushed, true);
            app.sendV1.Text = 'send';
            app.sendV3 = uibutton(app.Segmento0Tab, 'push');
            app.sendV3.ButtonPushedFcn = createCallbackFcn(app, @sendV3ButtonPushed, true);
            app.sendV3.Text = 'send';
            app.sendV2 = uibutton(app.Segmento0Tab, 'push');
            app.sendV2.ButtonPushedFcn = createCallbackFcn(app, @sendV2ButtonPushed, true);
            app.sendV2.Text = 'send';
            app.Segmento1Tab = uitab(app.Tabsmodomanual);
            app.Segmento1Tab.Title = 'Segmento 1';
            app.sendV4 = uibutton(app.Segmento1Tab, 'push');
            app.sendV4.ButtonPushedFcn = createCallbackFcn(app, @sendV4ButtonPushed, true);
            app.sendV4.Text = 'send';
            app.sendV6 = uibutton(app.Segmento1Tab, 'push');
            app.sendV6.ButtonPushedFcn = createCallbackFcn(app, @sendV6ButtonPushed, true);
            app.sendV6.Text = 'send';
            app.sendV5 = uibutton(app.Segmento1Tab, 'push');
            app.sendV5.ButtonPushedFcn = createCallbackFcn(app, @sendV5ButtonPushed, true);
            app.sendV5.Text = 'send';
            app.V4Gauge_2Label = uilabel(app.Segmento1Tab);
            app.V4Gauge_2Label.Text = 'V4';
            app.GaugeV4 = uigauge(app.Segmento1Tab, 'circular');
            app.GaugeV4.Limits = [-5000 5000];
            app.V5GaugeLabel = uilabel(app.Segmento1Tab);
            app.V5GaugeLabel.Text = 'V5';
            app.GaugeV5 = uigauge(app.Segmento1Tab, 'circular');
            app.GaugeV5.Limits = [-5000 5000];
            app.V0Gauge_3Label_2 = uilabel(app.Segmento1Tab);
            app.V0Gauge_3Label_2.Text = 'V6';
            app.GaugeV6 = uigauge(app.Segmento1Tab, 'circular');
            app.GaugeV6.Limits = [-5000 5000];
            app.Segmento2Tab = uitab(app.Tabsmodomanual);
            app.Segmento2Tab.Title = 'Segmento 2';
            app.sendV7 = uibutton(app.Segmento2Tab, 'push');
            app.sendV7.ButtonPushedFcn = createCallbackFcn(app, @sendV7ButtonPushed, true);
            app.sendV7.Text = 'send';
            app.sendV9 = uibutton(app.Segmento2Tab, 'push');
            app.sendV9.ButtonPushedFcn = createCallbackFcn(app, @sendV9ButtonPushed, true);
            app.sendV9.Text = 'send';
            app.sendV8 = uibutton(app.Segmento2Tab, 'push');
            app.sendV8.ButtonPushedFcn = createCallbackFcn(app, @sendV8ButtonPushed, true);
            app.sendV8.Text = 'send';
            app.V0Gauge_3Label_3 = uilabel(app.Segmento2Tab);
            app.V0Gauge_3Label_3.Text = 'V7';
            app.GaugeV7 = uigauge(app.Segmento2Tab, 'circular');
            app.GaugeV7.Limits = [-5000 5000];
            app.V8GaugeLabel = uilabel(app.Segmento2Tab);
            app.V8GaugeLabel.Text = 'V8';
            app.GaugeV8 = uigauge(app.Segmento2Tab, 'circular');
            app.GaugeV8.Limits = [-5000 5000];
            app.V0Gauge_3Label_4 = uilabel(app.Segmento2Tab);
            app.V0Gauge_3Label_4.Text = 'V9';
            app.GaugeV9 = uigauge(app.Segmento2Tab, 'circular');
            app.GaugeV9.Limits = [-5000 5000];
            app.VisionporcomputadorTab = uitab(app.InterfazPrincipal);
            app.VisionporcomputadorTab.Title = 'Vision por computador';
            app.CapturarposicionButton = uibutton(app.VisionporcomputadorTab, 'push');
            app.CapturarposicionButton.ButtonPushedFcn = createCallbackFcn(app, @CapturarposicionButtonPushed, true);
            app.CapturarposicionButton.Text = 'Capturar posicion';
            app.connect = uibutton(app.VisionporcomputadorTab, 'push');
            app.connect.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connect.Text = 'Connect';
            app.Panel = uipanel(app.VisionporcomputadorTab);
            app.Panel.Title = 'Panel';
            app.UIAxes_xz = uiaxes(app.Panel);
            title(app.UIAxes_xz, 'xz')
            xlabel(app.UIAxes_xz, 'X')
            ylabel(app.UIAxes_xz, 'Y')
            zlabel(app.UIAxes_xz, 'Z')
            app.EulerEditFieldLabel = uilabel(app.Panel);
            app.EulerEditFieldLabel.Text = 'Euler';
            app.EulerEditField = uieditfield(app.Panel, 'text');
            app.Panel_2 = uipanel(app.VisionporcomputadorTab);
            app.Panel_2.Title = 'Panel';
            app.UIAxes_yz = uiaxes(app.Panel_2);
            title(app.UIAxes_yz, 'yz')
            xlabel(app.UIAxes_yz, 'X')
            ylabel(app.UIAxes_yz, 'Y')
            zlabel(app.UIAxes_yz, 'Z')
            app.PosicionTextAreaLabel = uilabel(app.VisionporcomputadorTab);
            app.PosicionTextAreaLabel.Text = 'Posicion';
            app.PosicionText = uitextarea(app.VisionporcomputadorTab);
            app.zeroButton = uibutton(app.VisionporcomputadorTab, 'push');
            app.zeroButton.ButtonPushedFcn = createCallbackFcn(app, @zeroButtonPushed, true);
            app.zeroButton.Text = 'zero';
            app.Z_offsetEditFieldLabel = uilabel(app.VisionporcomputadorTab);
            app.Z_offsetEditFieldLabel.Text = 'Z_offset';
            app.Z_offsetEditField = uieditfield(app.VisionporcomputadorTab, 'numeric');
            app.setButton = uibutton(app.VisionporcomputadorTab, 'push');
            app.setButton.ButtonPushedFcn = createCallbackFcn(app, @setButtonPushed, true);
            app.setButton.Text = 'set';
            app.Panel2 = uipanel(app.VisionporcomputadorTab);
            app.Panel2.Title = 'Panel2';
            app.UIAxes = uiaxes(app.Panel2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tab = uitab(app.InterfazPrincipal);
            app.TabGroup = uitabgroup(app.Tab);
            app.AjustesTab = uitab(app.TabGroup);
            app.AjustesTab.Title = 'Ajustes';
            app.CapturadedatosTab = uitab(app.TabGroup);
            app.CapturadedatosTab.Title = 'Captura de datos';
            app.RecogidadedatosTab = uitab(app.InterfazPrincipal);
            app.RecogidadedatosTab.Title = 'Recogida de datos';
            app.NumerodemuestrasEditFieldLabel = uilabel(app.RecogidadedatosTab);
            app.NumerodemuestrasEditFieldLabel.Text = 'Numero de muestras';
            app.NumerodemuestrasEditField = uieditfield(app.RecogidadedatosTab, 'numeric');
            app.PresionmaximaEditFieldLabel = uilabel(app.RecogidadedatosTab);
            app.PresionmaximaEditFieldLabel.Text = 'Presion maxima';
            app.PresionmaximaEditField = uieditfield(app.RecogidadedatosTab, 'numeric');
            app.PresionminimaEditFieldLabel = uilabel(app.RecogidadedatosTab);
            app.PresionminimaEditFieldLabel.Text = 'Presion minima';
            app.PresionminimaEditField = uieditfield(app.RecogidadedatosTab, 'numeric');
            app.PpresostatoMpaEditFieldLabel = uilabel(app.RecogidadedatosTab);
            app.PpresostatoMpaEditFieldLabel.Text = 'P. presostato (Mpa)';
            app.PpresostatoMpaEditField = uieditfield(app.RecogidadedatosTab, 'numeric');
            app.SetButton = uibutton(app.RecogidadedatosTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.StartButton = uibutton(app.RecogidadedatosTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.RecogidadedatosTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TextAreaLabel = uilabel(app.RecogidadedatosTab);
            app.TextAreaLabel.Text = '';
            app.TextArea = uitextarea(app.RecogidadedatosTab);
            app.NombrevariableEditFieldLabel = uilabel(app.RecogidadedatosTab);
            app.NombrevariableEditFieldLabel.Text = 'Nombre variable';
            app.NombrevariableEditField = uieditfield(app.RecogidadedatosTab, 'text');
            app.ManualPanel = uipanel(app.RecogidadedatosTab);
            app.ManualPanel.Title = 'Manual';
            app.V1EditFieldLabel = uilabel(app.ManualPanel);
            app.V1EditFieldLabel.Text = 'V1';
            app.v1 = uieditfield(app.ManualPanel, 'numeric');
            app.V2EditFieldLabel = uilabel(app.ManualPanel);
            app.V2EditFieldLabel.Text = 'V2';
            app.v2 = uieditfield(app.ManualPanel, 'numeric');
            app.V3EditFieldLabel = uilabel(app.ManualPanel);
            app.V3EditFieldLabel.Text = 'V3';
            app.v3 = uieditfield(app.ManualPanel, 'numeric');
            app.v4 = uieditfield(app.ManualPanel, 'numeric');
            app.V1EditField_2Label_2 = uilabel(app.ManualPanel);
            app.V1EditField_2Label_2.Text = 'V5';
            app.v5 = uieditfield(app.ManualPanel, 'numeric');
            app.V1EditField_6 = uieditfield(app.ManualPanel, 'numeric');
            app.V1EditField_3Label_2 = uilabel(app.ManualPanel);
            app.V1EditField_3Label_2.Text = 'V6';
            app.v6 = uieditfield(app.ManualPanel, 'numeric');
            app.v7 = uieditfield(app.ManualPanel, 'numeric');
            app.v8 = uieditfield(app.ManualPanel, 'numeric');
            app.v9 = uieditfield(app.ManualPanel, 'numeric');
            app.EnviarButton = uibutton(app.ManualPanel, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Text = 'Enviar';
            app.TomarposicionButton = uibutton(app.ManualPanel, 'push');
            app.TomarposicionButton.Text = 'Tomar posicion';
            app.DesinflarButton = uibutton(app.ManualPanel, 'push');
            app.DesinflarButton.Text = 'Desinflar';
            app.GuardarButton = uibutton(app.ManualPanel, 'push');
            app.GuardarButton.Text = 'Guardar';
            app.VisionporcomputadorPanel = uipanel(app.RecogidadedatosTab);
            app.VisionporcomputadorPanel.Title = 'Vision por computador';
            app.Label_2 = uilabel(app.VisionporcomputadorPanel);
            app.Label_2.Text = '';
            app.Lamp_2 = uilamp(app.VisionporcomputadorPanel);
            app.GridLayout = uigridlayout(app.VisionporcomputadorPanel);
            app.datosxz = uiaxes(app.GridLayout);
            title(app.datosxz, 'XZ (USB cam)')
            xlabel(app.datosxz, 'X')
            ylabel(app.datosxz, 'Y')
            zlabel(app.datosxz, 'Z')
            app.datosyz = uiaxes(app.GridLayout);
            title(app.datosyz, 'YZ (Logitech)')
            xlabel(app.datosyz, 'X')
            ylabel(app.datosyz, 'Y')
            zlabel(app.datosyz, 'Z')
            app.CoordenadashomogeneasPanel = uipanel(app.RecogidadedatosTab);
            app.CoordenadashomogeneasPanel.Title = 'Coordenadas homogeneas';
            app.GridLayout2 = uigridlayout(app.CoordenadashomogeneasPanel);
            app.datosmth = uiaxes(app.GridLayout2);
            title(app.datosmth, 'mth')
            xlabel(app.datosmth, 'X')
            ylabel(app.datosmth, 'Y')
            zlabel(app.datosmth, 'Z')
            app.TempambienteEditFieldLabel = uilabel(app.RecogidadedatosTab);
            app.TempambienteEditFieldLabel.Text = 'Temp. ambiente';
            app.TempambienteEditField = uieditfield(app.RecogidadedatosTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end